classdef lf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        apertureSliderLabel    matlab.ui.control.Label
        apertureSlider         matlab.ui.control.Slider
        focalplaneSliderLabel  matlab.ui.control.Label
        focalplaneSlider       matlab.ui.control.Slider
        cameraXSliderLabel     matlab.ui.control.Label
        cameraXSlider          matlab.ui.control.Slider
        cameraYLabel           matlab.ui.control.Label
        cameraYSlider          matlab.ui.control.Slider
        cameraZSliderLabel     matlab.ui.control.Label
        cameraZSlider          matlab.ui.control.Slider
        RightPanel             matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%  x-y 轴位于光轴上，即uv-st平面中心
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % st平面
        img_width = 320;
        img_height = 240;
        imgs = zeros(16, 16, 240, 320, 3);  % 16*16=u,v  320 = s, 240 = t
        st_z = 0;
        color_chanel = 3;
        
        % uv平面/微透镜阵列
        uv_z = 1;
        aperture = 0; % 默认光圈
        % lens_location = zeros(16, 16, 2); % 每个透镜的实际x、y位置，可推出st平面每个像素的实际位置
        
        % 虚拟相机
        camera_x = 0; % x位于相机中心
        camera_y = 0; % y位于相机中心
        camera_z = 3;
        camera_width = 320; 
        camera_height = 240;
        focal_plane_z = 5; % 虚拟相机的焦距(焦平面与虚拟相机的相对距离)
        % camera_pixel_location = zeros(240, 320, 2); % 焦平面每个像素的实际位置
    end
    
    methods (Access = private)
        
        function img = img_show(app, method)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% 根据光圈、focal plane、相机位置、插值方式， 计算输出图像
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            img = zeros(app.camera_height, app.camera_width, app.color_chanel);
            
            for c_y = 1:app.camera_height
                for c_x = 1:app.camera_width
                    % 遍历相机焦平面的每个像素
                    
                    % 1.计算相机像素在空间中的实际坐标f,g
                    [f,g] = app.camera_pixel_location(c_y, c_x);
                    
                    % 2.计算从相机到像素坐标的光线在uv-st平面的坐标
                    % 计算(0, 0, camera_z) 与 (f, g, focal_plane_z) 在 z=0, z=1的解
                    % 参数方程
                    % x = x0 + kt
                    % y = y0 + mt
                    % z = z0 + nt
                    t = sqrt(f^2 + g^2 + app.focal_plane_z^2);
                    k = f / t;
                    m = g / t;
                    n = app.focal_plane_z / t;
                    x0 = 0;
                    y0 = 0;
                    z0 = app.camera_z;
                    
                    % 令z=1，计算u,v
                    t = (1 - z0) / n;
                    u = x0 + k * t;
                    v = y0 + m * t;
                    
                    % 令z=0，计算s,t
                    t = -z0 / n;
                    s = x0 + k * t;
                    t = y0 + m * t;
                    
                    %% 判断st合法性
%                     if s < ? or s > ? or t > ? or t < ?
%                         continue
%                     end
                    
                    % 3.获取u,v周围以 aperture 为半径的真实光线的u,v
                    us = [];
                    vs = [];
                    for i = 1:16
                        for j = 1:16
                            [u_, v_] = app.camera_pixel_location(i,j);
                            if sqrt((u-u_)^2 + (v-v_)^2) < app.aperture
                                us = [us, u_];
                                vs = [vs, v_];
                            end
                        end
                    end


                    % 4.进行插值/sparity得到真正的像素
                    ss = [];
                    ts = [];
                    if method == 'Quadra-linear'
                        % 将uv-st光线作为平行光
                        ds = round(s - u);
                        dt = round(t - v);
                        for i = 1:length(us)
                            ss = [ss, us(i) + ds];
                            ts = [ts, vs(i) + dt];
                        end
                        
                        % 根据uv进行插值
                        % 将uv-st真实位置转为数组中的索引(正值)！！！！！！！！！！！！！！！
                        % 简易版：取平均值
                        for i = 1:length(us)
                            img(c_y, c_x, :) = reshape(img(c_y, c_x) + app.imgs(us(i), vs(i), ts(i), ss(i), :), app.camera_heigh, app.camera_width, app.color_chanel);
                        end
                        img(c_y, c_x, :) = img(c_y, c_x, :) ./ length(us);
                        
                    elseif method == 'sparity'
                        % 将uv-st光线作为非平行光
                        % 注意检查st合法性
                        
                    end
                    
                    
                end
            end


            % test
%             img = reshape(app.imgs(1, 1, :, :, :), app.img_height, app.img_width, 3);
            
           
        end
        
        function [] = init_ui(app)
            % 初始化ui参数
            app.apertureSlider.Value = app.aperture; % 初始camera 阵列的光圈
            app.focalplaneSlider.Value = app.focal_plane_z; % 初始 focal plane
            app.cameraXSlider.Value = app.camera_x; % 相机x 
            app.cameraYSlider.Value = app.camera_y; % 相机y
            app.cameraZSlider.Value = app.camera_z; % 相机z
            app.focalplaneSlider.Value = app.focal_plane_z;
        end
        
        function [] = load_imgs(app)
            % 读取图片
            img_path_list = dir('*.bmp');
            for i = 1:16
                for j=1:16
                    app.imgs(i, j, :, :, :) = rescale( ...
                        imread(string(img_path_list((i-1)*16+j).name)), ...
                        0, 1); 
                end
            end
        end
        
        function [] = refresh_screen(app)
            % 生成新的图像并输出至屏幕
            img = app.img_show('Quadra-linear');
            imshow(img, 'Parent',app.UIAxes);
        end
       
        function [] = init_para(app)
            % 计算lens_location
%             for i = 1:16
%                 for j = 1:16
%                     app.lens_location(i,j,:) = [app.img_height*(8.5-i), app.img_width*(j-8.5)];
%                 end
%             end
            
            % 计算camera_pixel_location
%             for i = 1:app.camera_height
%                 for j = 1:app.camera_width
%                     app.camera_pixel_location(i,j,:) = [120.5-i, i-160.5];
%                 end
%             end
%             
        end
        
        function [f,g] = camera_pixel_location(app, c_y, c_x)
            f = c_x-160.5;
            g = 120.5-c_y;
        end
        
        function [u,v] = lens_location(app, i, j)
            % i,j 为 16*16网格中的纵坐标和横坐标
            u = app.img_width * (j-8.5);
            v = app.img_height * (8.5-i);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init_para(); % 初始化参数
            app.init_ui(); % 使用参数默认值初始化滚动条默认值
            app.load_imgs(); % 读取256张bmp图片
            app.refresh_screen(); % 输出图像
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {209, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: apertureSlider
        function apertureSliderValueChanged(app, event)
            % 改变光圈
            app.aperture = app.apertureSlider.Value;

            % 刷新图像
            app.refresh_screen();
        end

        % Value changed function: focalplaneSlider
        function focalplaneSliderValueChanged(app, event)
            % 改变焦距
            app.focal_plane_z = app.focalplaneSlider.Value;

            % 刷新图像
            app.refresh_screen();
        end

        % Value changed function: cameraXSlider
        function cameraXSliderValueChanged(app, event)
            % 改变相机x
            app.camera_x = app.cameraXSlider.Value;
            
            % 刷新图像
            app.refresh_screen();
        end

        % Value changed function: cameraYSlider
        function cameraYSliderValueChanged(app, event)
            % 改变相机y
            app.camera_y = app.cameraYSlider.Value;
            
            % 刷新图像
            app.refresh_screen();
        end

        % Value changed function: cameraZSlider
        function cameraZSliderValueChanged(app, event)
            % 改变相机z
            app.camera_z = app.cameraZSlider.Value;
            
            % 刷新图像
            app.refresh_screen();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.apertureSliderLabel = uilabel(app.LeftPanel);
            app.apertureSliderLabel.Text = 'aperture';
            app.apertureSlider = uislider(app.LeftPanel);
            app.apertureSlider.Limits = [0 1000];
            app.apertureSlider.ValueChangedFcn = createCallbackFcn(app, @apertureSliderValueChanged, true);
            app.apertureSlider.Tooltip = {''};
            app.apertureSlider.Value = 1;
            app.focalplaneSliderLabel = uilabel(app.LeftPanel);
            app.focalplaneSliderLabel.Text = 'focal plane';
            app.focalplaneSlider = uislider(app.LeftPanel);
            app.focalplaneSlider.Limits = [0 10];
            app.focalplaneSlider.ValueChangedFcn = createCallbackFcn(app, @focalplaneSliderValueChanged, true);
            app.cameraXSliderLabel = uilabel(app.LeftPanel);
            app.cameraXSliderLabel.Text = 'camera X';
            app.cameraXSlider = uislider(app.LeftPanel);
            app.cameraXSlider.Limits = [-1000 1000];
            app.cameraXSlider.ValueChangedFcn = createCallbackFcn(app, @cameraXSliderValueChanged, true);
            app.cameraYLabel = uilabel(app.LeftPanel);
            app.cameraYLabel.Text = 'camera Y';
            app.cameraYSlider = uislider(app.LeftPanel);
            app.cameraYSlider.Limits = [-1000 1000];
            app.cameraYSlider.ValueChangedFcn = createCallbackFcn(app, @cameraYSliderValueChanged, true);
            app.cameraZSliderLabel = uilabel(app.LeftPanel);
            app.cameraZSliderLabel.Text = 'camera Z';
            app.cameraZSlider = uislider(app.LeftPanel);
            app.cameraZSlider.Limits = [-1000 1000];
            app.cameraZSlider.ValueChangedFcn = createCallbackFcn(app, @cameraZSliderValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '320 x 240 virtual camera view')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end